classdef ELiDAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StateDropDown               matlab.ui.control.DropDown
        StateDropDownLabel          matlab.ui.control.Label
        ToolsPanel                  matlab.ui.container.Panel
        TabGroup2                   matlab.ui.container.TabGroup
        ProminenceTab               matlab.ui.container.Tab
        ProminenceEditField         matlab.ui.control.NumericEditField
        ProminenceEditFieldLabel    matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        SaddleElevEditField         matlab.ui.control.NumericEditField
        SaddleElevEditFieldLabel    matlab.ui.control.Label
        PeakElevEditField           matlab.ui.control.NumericEditField
        PeakElevEditFieldLabel      matlab.ui.control.Label
        ScratchTab                  matlab.ui.container.Tab
        SavetotxtButton             matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        CombineDataTab              matlab.ui.container.Tab
        CombineButton               matlab.ui.control.Button
        DefaultButton_2             matlab.ui.control.Button
        FolderButton_2              matlab.ui.control.Button
        CombinePathEditField        matlab.ui.control.EditField
        CombinePathEditFieldLabel   matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        READMEButton                matlab.ui.control.Button
        TakeSnapshotButton          matlab.ui.control.Button
        DefaultButton               matlab.ui.control.Button
        GoToSourceButton            matlab.ui.control.Button
        ReenableButton              matlab.ui.control.Button
        Label_7                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        ResultsPanel                matlab.ui.container.Panel
        LondegEditField             matlab.ui.control.EditField
        LondegEditFieldLabel        matlab.ui.control.Label
        LatdegEditField             matlab.ui.control.EditField
        LatdegEditFieldLabel        matlab.ui.control.Label
        ElevationmEditField         matlab.ui.control.EditField
        ElevationmEditFieldLabel    matlab.ui.control.Label
        ElevationftEditField        matlab.ui.control.EditField
        ElevationftEditFieldLabel   matlab.ui.control.Label
        AnalyzeButton               matlab.ui.control.Button
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ClosefigsButton             matlab.ui.control.Button
        RESETButton                 matlab.ui.control.Button
        FolderButton                matlab.ui.control.Button
        DataPathEditField           matlab.ui.control.EditField
        DataPathEditFieldLabel      matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        OptionsTab                  matlab.ui.container.Tab
        PlotLLACheckBox             matlab.ui.control.CheckBox
        SearchRadiusDropDown        matlab.ui.control.DropDown
        SearchRadiusDropDownLabel   matlab.ui.control.Label
        ForceUnitDropDown           matlab.ui.control.DropDown
        ForceUnitDropDownLabel      matlab.ui.control.Label
        ColorScaleDropDown          matlab.ui.control.DropDown
        ColorScaleDropDownLabel_2   matlab.ui.control.Label
        ZoomScaleDropDown           matlab.ui.control.DropDown
        ZoomScaleDropDownLabel      matlab.ui.control.Label
        GroundOnlyCheckBox          matlab.ui.control.CheckBox
        CleanDataCheckBox           matlab.ui.control.CheckBox
        AdvancedTab                 matlab.ui.container.Tab
        FilterThreshEditField       matlab.ui.control.NumericEditField
        FilterThreshEditFieldLabel  matlab.ui.control.Label
        FilternNbrEditField         matlab.ui.control.NumericEditField
        FilternNbrEditFieldLabel    matlab.ui.control.Label
        QueryTypePanel              matlab.ui.container.Panel
        SelectListBox               matlab.ui.control.ListBox
        SelectListBoxLabel          matlab.ui.control.Label
        EnterQueryInfoPanel         matlab.ui.container.Panel
        GuessCenterCheckBox         matlab.ui.control.CheckBox
        LonEditField                matlab.ui.control.NumericEditField
        LonEditFieldLabel           matlab.ui.control.Label
        LatEditField                matlab.ui.control.NumericEditField
        LatEditFieldLabel           matlab.ui.control.Label
        SandWarenegativeLabel       matlab.ui.control.Label
        XXXXXXYYYYYYLabel           matlab.ui.control.Label
        FormatDecimalDegreesLabel   matlab.ui.control.Label
        ELiDARGUILabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        lidar_path
        dem_path
        outdata
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            folder = uigetdir;
            delete(f)
            if ~isempty(folder) && ischar(folder) && isfolder(folder)
                app.DataPathEditField.Value = folder;
                app.DataPathEditFieldValueChanged;
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            inp = 'Yes';
            if any(~cellfun(@isempty,app.TextArea.Value))
                inp = questdlg('You have notes, are you sure you want to close?', 'Check Notes', 'Yes', 'No', 'No');
            end

            if strcmp(inp, 'Yes')
                close(findall(0,'type','Figure'));
                ELiDAR;
            end
        end

        % Button pushed function: ClosefigsButton
        function ClosefigsButtonPushed(app, event)
            close(findobj('Type', 'figure'))
        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            value = app.DataPathEditField.Value;
            % Look in folder for LiDAR data (laz/las extension) and dem data (tif/img extension)
            d = dir(value);
            n = 0;
            m = 0;
            for ii = 1:length(d)
                if contains(d(ii).name, '.laz') || contains(d(ii).name, '.las')
                    n = n+1;
                    if n > 1
                        error('Multiple las/laz files found, please point to a directory containing only one!')
                    end
                    app.lidar_path = fullfile(value, d(ii).name);
                elseif contains(d(ii).name, '.tif') || contains(d(ii).name, '.img')
                    m = m+1;
                    if m > 1
                        error('Multiple tif/img files found, please point to a directory containing only one!')
                    end
                    app.dem_path = fullfile(value, d(ii).name);
                end
            end
        end

        % Value changed function: SaddleElevEditField
        function SaddleElevEditFieldValueChanged(app, event)
            value = app.SaddleElevEditField.Value;
            app.ProminenceEditField.Value = app.PeakElevEditField.Value - app.SaddleElevEditField.Value;
        end

        % Value changed function: PeakElevEditField
        function PeakElevEditFieldValueChanged(app, event)
            value = app.PeakElevEditField.Value;
            app.ProminenceEditField.Value = app.PeakElevEditField.Value - app.SaddleElevEditField.Value;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % Run queryLidar with inputs
            app.AnalyzeButton.Enable = false;
            pause(0.1)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            center = false;
            if app.GuessCenterCheckBox.Value
                center = true;
            end

            try
                app.outdata = queryLidar(app.LatEditField.Value, app.LonEditField.Value, app.SelectListBox.Value, app.lidar_path, app.dem_path, ...
                    'cleanData', app.CleanDataCheckBox.Value, 'colorscale', app.ColorScaleDropDown.Value, 'filterGround', app.GroundOnlyCheckBox.Value, ...
                    'queryRadius_deg', str2num(app.SearchRadiusDropDown.Value), 'zoomScale', str2num(app.ZoomScaleDropDown.Value), 'forceUnit', app.ForceUnitDropDown.Value, ...
                    'filterNumNbr', app.FilternNbrEditField.Value, 'filterThreshold', app.FilterThreshEditField.Value, 'displayLLA', app.PlotLLACheckBox.Value, ...
                    'center', center);
                if ~isempty(app.outdata)
                    app.ElevationftEditField.Value = num2str(app.outdata.([app.SelectListBox.Value,'_elevation_ft']));
                    app.ElevationmEditField.Value = num2str(app.outdata.([app.SelectListBox.Value,'_elevation_m']));
                    latlon = app.outdata.([app.SelectListBox.Value,'_location_deg']);
                    app.LatdegEditField.Value = num2str(latlon(1));
                    app.LondegEditField.Value = num2str(latlon(2));
                end
                
            catch ME
                error(ME.getReport)
            end

            close(f)
            app.AnalyzeButton.Enable = true;
            
        end

        % Button pushed function: ReenableButton
        function ReenableButtonPushed(app, event)
            app.AnalyzeButton.Enable = true;
            app.TakeSnapshotButton.Enable = true;   
        end

        % Button pushed function: GoToSourceButton
        function GoToSourceButtonPushed(app, event)
            switch app.StateDropDown.Value
                case 'CO'
                    web('https://coloradohazardmapping.com/lidarDownload', '-browser')
                case 'VA'
                    web('https://vgin.vdem.virginia.gov/maps/VGIN::virginia-lidar-download/explore?location=37.644250%2C-79.350102%2C6.35', '-browser')
            end
        end

        % Button pushed function: SavetotxtButton
        function SavetotxtButtonPushed(app, event)
            txt = app.TextArea.Value;
            path = app.DataPathEditField.Value;
            if isfolder(path)
                writecell(txt,fullfile(path,'Notes.txt'))
                app.SavetotxtButton.Enable = false;
                pause(1)
                app.SavetotxtButton.Enable = true;
            else
                error('No Valid Path Specified')
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % Get location from defaultLocation.txt
            fileID = fopen('defaultLocation.txt','r');
            data = textscan(fileID, '%s');
            fclose(fileID);
            if isfolder(data{1}{1})
                folder = uigetdir(data{1}{1});
            else
                warning('defaultLocation.txt not found')
            end
            delete(f)
            if ~isempty(folder) && ischar(folder) && isfolder(folder)
                app.DataPathEditField.Value = folder;
                app.DataPathEditFieldValueChanged;
            end
        end

        % Button pushed function: FolderButton_2
        function FolderButton_2Pushed(app, event)
            folder = uigetdir;
            if ~isempty(folder) && ischar(folder) && isfolder(folder)
                app.CombinePathEditField.Value = folder;
                app.CombinePathEditField;
            end
        end

        % Button pushed function: DefaultButton_2
        function DefaultButton_2Pushed(app, event)
            folder = uigetdir('C:\Users\e416680\Documents\Lidar');
            if ~isempty(folder) && ischar(folder) && isfolder(folder)
                app.CombinePathEditField.Value = folder;
                app.CombinePathEditField;
            end
        end

        % Value changed function: CombinePathEditField
        function CombinePathEditFieldValueChanged(app, event)
            value = app.CombinePathEditField.Value;
            % Look in folder for LiDAR data (laz/las extension) and dem data (tif/img extension)
            d = dir(value);
            n = 0;
            m = 0;
            for ii = 1:length(d)
                if contains(d(ii).name, '.laz') || contains(d(ii).name, '.las')
                    n = n+1;
                    app.lidar_path = fullfile(value, d(ii).name);
                elseif contains(d(ii).name, '.tif') || contains(d(ii).name, '.img')
                    m = m+1;
                    app.dem_path = fullfile(value, d(ii).name);
                end
            end

            if n == 0
                error('No las/laz files found')
            elseif n == 1
                error('Only one las/laz file found, this tool is for combining multiple')
            end

            if n~=m
                error('Every las/laz file must have associated tif/img')
            end
        end

        % Button pushed function: CombineButton
        function CombineButtonPushed(app, event)
            val = app.CombinePathEditField.Value;
            if ~isempty(val) && isfolder(val)
                combineLAS(val)
            else
                error('Nothing to combine')
            end
        end

        % Button pushed function: TakeSnapshotButton
        function TakeSnapshotButtonPushed(app, event)
            app.TakeSnapshotButton.Enable = false;
            pause(0.3)
            f = findall(0,'type','figure');
            if numel(f) ~= 0
                for ii = 1:numel(f)
                    if isprop(f(ii), 'Children') && ~isempty(f(ii).Children) && ...
                            isprop(f(ii).Children(1), 'Children') && ~isempty(f(ii).Children(1).Children) && ...
                            contains(class(f(ii).Children(1).Children), 'Scatter')
                        % Save png of current figure
                        filename = fullfile(app.DataPathEditField.Value, 'Snapshot');
                        filename2 = [filename, '.png'];
                        n = 1;
                        while isfile(filename2)
                            filename2 = [filename,'_',num2str(n),'.png'];
                            n=n+1;
                        end
                        print(f(ii),filename2,'-dpng')
                    end
                end
            end
            app.TakeSnapshotButton.Enable = true;
        end

        % Button pushed function: READMEButton
        function READMEButtonPushed(app, event)
            readme = fullfile(fileparts(mfilename('fullpath')), 'README.txt');
            if isfile(readme)
                !notepad readme
            else
                warning('README.txt not found in directory')
            end         
        end

        % Value changed function: GuessCenterCheckBox
        function GuessCenterCheckBoxValueChanged(app, event)
            value = app.GuessCenterCheckBox.Value;
            app.LatEditField.Enable = ~value;
            app.LonEditField.Enable = ~value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ELiDARGUILabel = uilabel(app.UIFigure);
            app.ELiDARGUILabel.Text = 'ELiDAR GUI';
            app.EnterQueryInfoPanel = uipanel(app.UIFigure);
            app.EnterQueryInfoPanel.Title = 'Enter Query Info';
            app.FormatDecimalDegreesLabel = uilabel(app.EnterQueryInfoPanel);
            app.FormatDecimalDegreesLabel.Text = 'Format: Decimal Degrees';
            app.XXXXXXYYYYYYLabel = uilabel(app.EnterQueryInfoPanel);
            app.XXXXXXYYYYYYLabel.Text = 'XX.XXXX, YY.YYYY';
            app.SandWarenegativeLabel = uilabel(app.EnterQueryInfoPanel);
            app.SandWarenegativeLabel.Text = 'S and W are negative';
            app.LatEditFieldLabel = uilabel(app.EnterQueryInfoPanel);
            app.LatEditFieldLabel.Text = 'Lat:';
            app.LatEditField = uieditfield(app.EnterQueryInfoPanel, 'numeric');
            app.LatEditField.Limits = [-90 90];
            app.LatEditField.ValueDisplayFormat = '%.6f';
            app.LatEditField.Tooltip = {'-90 to 90'};
            app.LonEditFieldLabel = uilabel(app.EnterQueryInfoPanel);
            app.LonEditFieldLabel.Text = 'Lon:';
            app.LonEditField = uieditfield(app.EnterQueryInfoPanel, 'numeric');
            app.LonEditField.Limits = [-180 180];
            app.LonEditField.ValueDisplayFormat = '%.6f';
            app.LonEditField.Tooltip = {'-180 to 180'};
            app.GuessCenterCheckBox = uicheckbox(app.EnterQueryInfoPanel);
            app.GuessCenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @GuessCenterCheckBoxValueChanged, true);
            app.GuessCenterCheckBox.Text = 'Guess Center';
            app.QueryTypePanel = uipanel(app.UIFigure);
            app.QueryTypePanel.Title = 'Query Type';
            app.SelectListBoxLabel = uilabel(app.QueryTypePanel);
            app.SelectListBoxLabel.Text = 'Select:';
            app.SelectListBox = uilistbox(app.QueryTypePanel);
            app.SelectListBox.Items = {'search', 'peak', 'point'};
            app.SelectListBox.Value = 'search';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.CleanDataCheckBox = uicheckbox(app.OptionsTab);
            app.CleanDataCheckBox.Tooltip = {'Run MATLAB LiDAR Cleaning Algorithm'};
            app.CleanDataCheckBox.Text = 'Clean Data';
            app.CleanDataCheckBox.Value = true;
            app.GroundOnlyCheckBox = uicheckbox(app.OptionsTab);
            app.GroundOnlyCheckBox.Tooltip = {'May Remove Rocky Features'};
            app.GroundOnlyCheckBox.Text = 'Ground Only';
            app.ZoomScaleDropDownLabel = uilabel(app.OptionsTab);
            app.ZoomScaleDropDownLabel.Text = 'Zoom Scale';
            app.ZoomScaleDropDown = uidropdown(app.OptionsTab);
            app.ZoomScaleDropDown.Items = {'0.3', '0.5', '0.7'};
            app.ZoomScaleDropDown.Tooltip = {'Multiplier to plot size'};
            app.ZoomScaleDropDown.Value = '0.5';
            app.ColorScaleDropDownLabel_2 = uilabel(app.OptionsTab);
            app.ColorScaleDropDownLabel_2.Text = 'Color Scale';
            app.ColorScaleDropDown = uidropdown(app.OptionsTab);
            app.ColorScaleDropDown.Items = {'jet', 'BR', 'Greyscale', 'turbo', 'winter'};
            app.ColorScaleDropDown.Tooltip = {'Jet does not work for combined data'};
            app.ColorScaleDropDown.Value = 'jet';
            app.ForceUnitDropDownLabel = uilabel(app.OptionsTab);
            app.ForceUnitDropDownLabel.Text = 'Force Unit';
            app.ForceUnitDropDown = uidropdown(app.OptionsTab);
            app.ForceUnitDropDown.Items = {'N/A', 'ft', 'm'};
            app.ForceUnitDropDown.Tooltip = {'Use this only if tool is not converting values properly'};
            app.ForceUnitDropDown.Value = 'N/A';
            app.SearchRadiusDropDownLabel = uilabel(app.OptionsTab);
            app.SearchRadiusDropDownLabel.Text = 'Search Radius';
            app.SearchRadiusDropDown = uidropdown(app.OptionsTab);
            app.SearchRadiusDropDown.Items = {'0.0005', '0.0008', '0.0010', '0.0015', '0.0020'};
            app.SearchRadiusDropDown.Tooltip = {'Initial display radius around query, deg'};
            app.SearchRadiusDropDown.Value = '0.0008';
            app.PlotLLACheckBox = uicheckbox(app.OptionsTab);
            app.PlotLLACheckBox.Tooltip = {'Use LLA Coords in Intermitent Displays'};
            app.PlotLLACheckBox.Text = 'Plot LLA';
            app.PlotLLACheckBox.Value = true;
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.FilternNbrEditFieldLabel = uilabel(app.AdvancedTab);
            app.FilternNbrEditFieldLabel.Text = 'Filter n Nbr:';
            app.FilternNbrEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.FilternNbrEditField.Value = 20;
            app.FilterThreshEditFieldLabel = uilabel(app.AdvancedTab);
            app.FilterThreshEditFieldLabel.Text = 'Filter Thresh:';
            app.FilterThreshEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.FilterThreshEditField.Value = 1;
            app.DataPathEditFieldLabel = uilabel(app.UIFigure);
            app.DataPathEditFieldLabel.Text = 'Data Path:';
            app.DataPathEditField = uieditfield(app.UIFigure, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ClosefigsButton = uibutton(app.UIFigure, 'push');
            app.ClosefigsButton.ButtonPushedFcn = createCallbackFcn(app, @ClosefigsButtonPushed, true);
            app.ClosefigsButton.Text = 'Close figs';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'This points to a folder containing the .laz/.las and .tif/.img files that contain a query of interest';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ElevationftEditFieldLabel = uilabel(app.ResultsPanel);
            app.ElevationftEditFieldLabel.Text = 'Elevation (ft):';
            app.ElevationftEditField = uieditfield(app.ResultsPanel, 'text');
            app.ElevationftEditField.Editable = 'off';
            app.ElevationmEditFieldLabel = uilabel(app.ResultsPanel);
            app.ElevationmEditFieldLabel.Text = 'Elevation (m):';
            app.ElevationmEditField = uieditfield(app.ResultsPanel, 'text');
            app.ElevationmEditField.Editable = 'off';
            app.LatdegEditFieldLabel = uilabel(app.ResultsPanel);
            app.LatdegEditFieldLabel.Text = 'Lat (deg):';
            app.LatdegEditField = uieditfield(app.ResultsPanel, 'text');
            app.LatdegEditField.Editable = 'off';
            app.LondegEditFieldLabel = uilabel(app.ResultsPanel);
            app.LondegEditFieldLabel.Text = 'Lon (deg):';
            app.LondegEditField = uieditfield(app.ResultsPanel, 'text');
            app.LondegEditField.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '->';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.ReenableButton = uibutton(app.UIFigure, 'push');
            app.ReenableButton.ButtonPushedFcn = createCallbackFcn(app, @ReenableButtonPushed, true);
            app.ReenableButton.Text = 'Re-enable';
            app.GoToSourceButton = uibutton(app.UIFigure, 'push');
            app.GoToSourceButton.ButtonPushedFcn = createCallbackFcn(app, @GoToSourceButtonPushed, true);
            app.GoToSourceButton.Text = 'Go To Source';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.TakeSnapshotButton = uibutton(app.UIFigure, 'push');
            app.TakeSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapshotButtonPushed, true);
            app.TakeSnapshotButton.Tooltip = {'Will save as Snapshot.png to Data Path'};
            app.TakeSnapshotButton.Text = 'Take Snapshot';
            app.READMEButton = uibutton(app.UIFigure, 'push');
            app.READMEButton.ButtonPushedFcn = createCallbackFcn(app, @READMEButtonPushed, true);
            app.READMEButton.Text = 'README';
            app.ToolsPanel = uipanel(app.UIFigure);
            app.ToolsPanel.Title = 'Tools';
            app.TabGroup2 = uitabgroup(app.ToolsPanel);
            app.ProminenceTab = uitab(app.TabGroup2);
            app.ProminenceTab.Title = 'Prominence';
            app.PeakElevEditFieldLabel = uilabel(app.ProminenceTab);
            app.PeakElevEditFieldLabel.Text = 'Peak Elev:';
            app.PeakElevEditField = uieditfield(app.ProminenceTab, 'numeric');
            app.PeakElevEditField.ValueDisplayFormat = '%.0f';
            app.PeakElevEditField.ValueChangedFcn = createCallbackFcn(app, @PeakElevEditFieldValueChanged, true);
            app.SaddleElevEditFieldLabel = uilabel(app.ProminenceTab);
            app.SaddleElevEditFieldLabel.Text = 'Saddle Elev:';
            app.SaddleElevEditField = uieditfield(app.ProminenceTab, 'numeric');
            app.SaddleElevEditField.ValueDisplayFormat = '%.0f';
            app.SaddleElevEditField.ValueChangedFcn = createCallbackFcn(app, @SaddleElevEditFieldValueChanged, true);
            app.Label_8 = uilabel(app.ProminenceTab);
            app.Label_8.Text = '=';
            app.ProminenceEditFieldLabel = uilabel(app.ProminenceTab);
            app.ProminenceEditFieldLabel.Enable = 'off';
            app.ProminenceEditFieldLabel.Text = 'Prominence:';
            app.ProminenceEditField = uieditfield(app.ProminenceTab, 'numeric');
            app.ProminenceEditField.ValueDisplayFormat = '%.0f';
            app.ScratchTab = uitab(app.TabGroup2);
            app.ScratchTab.Title = 'Scratch';
            app.TextArea = uitextarea(app.ScratchTab);
            app.SavetotxtButton = uibutton(app.ScratchTab, 'push');
            app.SavetotxtButton.ButtonPushedFcn = createCallbackFcn(app, @SavetotxtButtonPushed, true);
            app.SavetotxtButton.Tooltip = {'Saves "Notes.txt" to Data Path, will overwrite'};
            app.SavetotxtButton.Text = 'Save to .txt';
            app.CombineDataTab = uitab(app.TabGroup2);
            app.CombineDataTab.Title = 'Combine Data';
            app.Label_9 = uilabel(app.CombineDataTab);
            app.Label_9.Text = 'Point to a directory with multiple .laz/.las and respective .tif/.img files, this will combine the point clouds into a custom format in LLA (deg deg m) that can be read back in by this GUI';
            app.CombinePathEditFieldLabel = uilabel(app.CombineDataTab);
            app.CombinePathEditFieldLabel.Text = 'Combine Path:';
            app.CombinePathEditField = uieditfield(app.CombineDataTab, 'text');
            app.CombinePathEditField.ValueChangedFcn = createCallbackFcn(app, @CombinePathEditFieldValueChanged, true);
            app.FolderButton_2 = uibutton(app.CombineDataTab, 'push');
            app.FolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @FolderButton_2Pushed, true);
            app.FolderButton_2.Text = 'Folder';
            app.DefaultButton_2 = uibutton(app.CombineDataTab, 'push');
            app.DefaultButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_2Pushed, true);
            app.DefaultButton_2.Text = 'Default';
            app.CombineButton = uibutton(app.CombineDataTab, 'push');
            app.CombineButton.ButtonPushedFcn = createCallbackFcn(app, @CombineButtonPushed, true);
            app.CombineButton.Text = 'Combine';
            app.StateDropDownLabel = uilabel(app.UIFigure);
            app.StateDropDownLabel.Text = 'State:';
            app.StateDropDown = uidropdown(app.UIFigure);
            app.StateDropDown.Items = {'CO', 'VA'};
            app.StateDropDown.Value = 'CO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELiDAR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end